classdef IMUcalibr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IMUcalibr100UIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        Tab_3                 matlab.ui.container.Tab
        UIAxesOut             matlab.ui.control.UIAxes
        Tab_2                 matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        RightPanel            matlab.ui.container.Panel
        ButtonSTNO            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Button_5              matlab.ui.control.Button
        ZButton               matlab.ui.control.Button
        YButton               matlab.ui.control.Button
        XButton               matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % Description
        g_data % Description
        number % Description
        filename % Description
    end
    
    methods (Access = private)
        
        function [ data ] = importNavFile(app, filename )
            %importNavFile импортирует структуру данных:
            %
            % struct in_data_t  //Заголовок файла   s=34
            % {
            %        unsigned short   count;
            %        float            accValues[3];
            %        unsigned short   accCount[3];
            %        float            gyroValues[3];
            %        float            period;
            %        unsigned short   sensorsStatus;
            %        unsigned short   muxData;
            % };

            fig = app.IMUcalibr100UIFigure;
            d = uiprogressdlg(fig,'Title','Обработка nav файла',...
                'Message','Определение размера файла...');
            drawnow;
            PAKET_SIZE = 40;
            
            fid = fopen(filename ,'r');
            [~, c] = fread(fid,inf,'40*char');
            p = floor(c/PAKET_SIZE);
            
            data.count = zeros(p,1);
            data.acc  = zeros(p,3);
            data.accCount = zeros(p,3);
            data.gyro = zeros(p,3);
            data.period = zeros(p,1);
            data.sensStatus = zeros(p,1);
            data.mux  = zeros(uint32(p/64),64);
            data.crc = zeros(p,1);
            
            fseek(fid, 0, 'bof');
            
            
            d.Message = 'Конвертация файла...';
            drawnow;
            
            for i=1:p
                data.count(i,1)       = fread(fid,1,'uint16');
                data.acc(i,:)         = fread(fid,3,'float32');
                data.accCount(i,:)    = fread(fid,3,'int16');
                
                w = fread(fid,3,'float32');
                
                data.gyro(i, 1) = w(1);
                data.gyro(i, 2) = w(2);
                data.gyro(i, 3) = w(3);
                
                data.period(i,:)      = fread(fid,1,'float32');
                data.sensStatus(i,:)  = fread(fid,1,'uint16');
                data.mux(floor(i/64)+1,mod(data.count(i,1),64)+1) = fread(fid,1,'int16');
                if mod(i,floor(p/100))==0
                    fprintf('.');
                    d.Value = i/p;
                    drawnow;
                end
            end
            
            data.mux(:,1:10) = data.mux(:,1:10)*0.05;
            
            fprintf('done\n');
            d.Message = "Завершено";
            d.close();
            fclose(fid);
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.filename, path, filterIndex] = uigetfile('*.nav;*.dat;*.txt;*.rpz;*.mat','MultiSelect', 'off');
            app.filename = strcat(path,app.filename);
            
            [~,~,ext] = fileparts(app.filename);
            if strcmp(ext,'.mat')
                load(app.filename);
                app.data = data;
            else
                app.data = app.importNavFile(app.filename);
            end
            
            [q1,q2] = butter(2, 0.1);
            
            plot(app.UIAxes, app.data.gyro);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{1,1}.gyro = app.data.gyro(l(1):l(2),:);   % Выставка 0
            app.g_data{1,1}.period = app.data.period(l(1):l(2),:);   % Выставка 0
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{7,1}.gyro = app.data.gyro(l(1):l(2),:);   % Выставка 90
            app.g_data{7,1}.period = app.data.period(l(1):l(2),:);   % Выставка 90
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{13,1}.gyro = app.data.gyro(l(1):l(2),:);   % Выставка 180
            app.g_data{13,1}.period = app.data.period(l(1):l(2),:);   % Выставка 180
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{2,1}.gyro = app.data.gyro(l(1):l(2),:);   % Поворот X
            app.g_data{2,1}.period = app.data.period(l(1):l(2),:);   % Поворот X
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{6,1}.gyro = app.data.gyro(l(1):l(2),:);   % Поворот Y
            app.g_data{6,1}.period = app.data.period(l(1):l(2),:);   % Поворот Y
            
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            l = int64(xlim(app.UIAxes));
            app.Label.Text = int2str(l(1))+"..."+int2str(l(2));
            app.g_data{8,1}.gyro = app.data.gyro(l(1):l(2),:);   % Поворот Z
            app.g_data{8,1}.period = app.data.period(l(1):l(2),:);   % Поворот Z
            
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [res, f, m] = getGyroModel(app.g_data);
            app.UITable.Data = m.a;
            saveToIniFile(app.filename+".ini", res);
            save(app.filename+".model.mat", 'res', "m");
            saveToBinFile(app.filename+".init_mat", m);
            
            [q1,q2] = butter(2, 1/125/10);
            w_calibr = applyGyroModel(filter(q1,q2, app.data.gyro), m.k, m.z, m.m, m.T);
            plot(app.UIAxesOut, w_calibr);
        end

        % Callback function
        function ZoomoutviewButtonPushed(app, event)
            
        end

        % Button pushed function: ButtonSTNO
        function ButtonSTNOPushed(app, event)
            [app.filename, path, filterIndex] = uigetfile('*.bin;*.mat','MultiSelect', 'off');
            app.filename = strcat(path,app.filename);
            
            [~,~,ext] = fileparts(app.filename);
            if strcmp(ext,'.mat')
                load(app.filename);
                app.data = data;
            else
                app.data = importSTNOFile(app.filename);
            end
            
            [q1,q2] = butter(2, 0.1);
            
            plot(app.UIAxes, filter(q1, q2, app.data.gyro));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IMUcalibr100UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {698, 698};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {776, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IMUcalibr100UIFigure = uifigure('Visible', 'off');
            app.IMUcalibr100UIFigure.Name = 'IMU calibr 1.0.0';
            app.IMUcalibr100UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IMUcalibr100UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'График';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Выбор участка')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Калиброванный выход';
            app.UIAxesOut = uiaxes(app.Tab_3);
            xlabel(app.UIAxesOut, 'X')
            ylabel(app.UIAxesOut, 'Y')
            zlabel(app.UIAxesOut, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Таблица';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'k1'; 'k2'; 'k3'; 'w1'; 'w2'; 'w3'; 'M'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Открыть файл';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Выставка 0';
            app.Button_3 = uibutton(app.RightPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Выставка 90';
            app.Button_4 = uibutton(app.RightPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Выставка 180';
            app.XButton = uibutton(app.RightPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'Поворот X';
            app.YButton = uibutton(app.RightPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Поворот Y';
            app.ZButton = uibutton(app.RightPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Поворот Z';
            app.Button_5 = uibutton(app.RightPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Расчет';
            app.Label = uilabel(app.RightPanel);
            app.ButtonSTNO = uibutton(app.RightPanel, 'push');
            app.ButtonSTNO.ButtonPushedFcn = createCallbackFcn(app, @ButtonSTNOPushed, true);
            app.ButtonSTNO.Text = 'Открыть файл';
            % Show the figure after all components are created
            app.IMUcalibr100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMUcalibr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IMUcalibr100UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IMUcalibr100UIFigure)
        end
    end
end